classdef FFMpegVideos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FFMpegCompressVideosUIFigure    matlab.ui.Figure
        CancelNextVideoButton           matlab.ui.control.StateButton
        VersionLabel                    matlab.ui.control.Label
        Help                            matlab.ui.control.Button
        mp4Label                        matlab.ui.control.Label
        AddtooutputfilenameLabel        matlab.ui.control.Label
        FFMpegCompressVideosLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EditField_CompressionFactorEstimate  matlab.ui.control.EditField
        Button_RunFFMpeg                matlab.ui.control.Button
        Button_ResetDestination         matlab.ui.control.Button
        Button_ChooseDestination        matlab.ui.control.Button
        EditField_Destination           matlab.ui.control.EditField
        DestinationfolderLabel          matlab.ui.control.Label
        EditField_FileSuffix            matlab.ui.control.EditField
        FilesuffixLabel                 matlab.ui.control.Label
        EditField_FilePrefix            matlab.ui.control.EditField
        FileprefixEditFieldLabel        matlab.ui.control.Label
        Switch_DeleteSource             matlab.ui.control.Switch
        DeletesourcefilesSwitchLabel    matlab.ui.control.Label
        EditField_CompressionFactor     matlab.ui.control.NumericEditField
        Compressionfactorx264CrfLabel   matlab.ui.control.Label
        Button_AddVideos                matlab.ui.control.Button
        Button_RemoveVideos             matlab.ui.control.Button
        Button_ChooseFolders            matlab.ui.control.Button
        ListBox_videos_toCompress       matlab.ui.control.ListBox
        ListofavivideostocompressLabel  matlab.ui.control.Label
        ListBox_videos_all              matlab.ui.control.ListBox
        ListofallavifilesintheselectedfoldersincludingsubfoldersLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        VideoFiles % Description
    end
    
    methods (Access = private)
        
        function estimatedCrf = EstimateCompressionFactor(~,x264Crf)
            estimatedCrf = 2^((x264Crf)/6);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            path_app     = fileparts(mfilename('fullpath'));
            addpath(genpath(path_app));
            if isempty(which('ffmpegtranscode'))
                % throw error
                uiwait(errordlg('Something is wrong with the FFMpeg Toolbox dependency','Error','modal'));
                return
            end
            
            x264Crf      = app.EditField_CompressionFactor.Value;
            estimatedCrf = EstimateCompressionFactor(app,x264Crf);
            app.EditField_CompressionFactorEstimate.Value = ['~' num2str(estimatedCrf,'%4.0f') 'x'];
            
            fprintf('\n  ~~~~~ FFMpeg Compress Videos ~~~~~ \n')
        end

        % Button pushed function: Button_ChooseFolders
        function Button_ChooseFoldersPushed(app, event)
            % Clear listbox (in case there are files already listed)
            app.ListBox_videos_all.Items        = {};
            % Pick files and/or folders:
            FilesOrFolders = uipickfiles('FilterSpec','*.avi','Prompt','Select files or folders', 'Output','cell');
            Files = struct;
            cnt = 0;
            
            for f = 1 : length(FilesOrFolders)
                if isfile(FilesOrFolders{f})
                    [filepath,name,ext] = fileparts(FilesOrFolders{f});
                    if strcmpi(ext,'.avi')
                        cnt = cnt + 1;
                        Files(cnt).folder = filepath;
                        Files(cnt).name = name;
                        Files(cnt).ext = ext;
                        Files(cnt).fullpath_input  = fullfile(Files(cnt).folder, [Files(cnt).name, Files(cnt).ext]);
                        %                         Files(cnt).fullpath_output = fullfile(Files(cnt).folder, [filePrefix, Files(cnt).name, fileSuffix, '.mp4']);
                        s = dir(Files(cnt).fullpath_input);
                        Files(cnt).size_bytes_input = s.bytes;
                    end
                elseif isfolder(FilesOrFolders{f})
                    listing = dir([FilesOrFolders{f} '/**/*.avi']);
                    for i = 1 : length(listing)
                        cnt = cnt + 1;
                        [filepath,name,ext] = fileparts(fullfile(listing(i).folder, listing(i).name));
                        Files(cnt).folder           = filepath;
                        Files(cnt).name             = name;
                        Files(cnt).ext              = ext;
                        Files(cnt).fullpath_input   = fullfile(Files(cnt).folder, [Files(cnt).name, Files(cnt).ext]);
                        %                         Files(cnt).fullpath_output  = fullfile(Files(cnt).folder, [filePrefix, Files(cnt).name, fileSuffix, '.mp4']);
                        s = dir(Files(cnt).fullpath_input);
                        Files(cnt).size_bytes_input = s.bytes;
                    end
                else
                    fprintf(' No .avi files found.\n')
                end
            end
            if isempty(fieldnames(Files))
                fprintf('\n No .avi files found.\n')
            else
                app.ListBox_videos_all.Items        = {Files.fullpath_input};
            end
        end

        % Button pushed function: Button_RemoveVideos
        function Button_RemoveVideosPushed(app, event)
            files_toRemove = app.ListBox_videos_toCompress.Value;
            fileInds_toKeep = find(~contains(app.ListBox_videos_toCompress.Items, files_toRemove));
            app.ListBox_videos_toCompress.Items = app.ListBox_videos_toCompress.Items(fileInds_toKeep);
        end

        % Button pushed function: Button_AddVideos
        function Button_AddVideosPushed(app, event)
            files_toAdd = app.ListBox_videos_all.Value;
            newList = [app.ListBox_videos_toCompress.Items, files_toAdd{:}];
            app.ListBox_videos_toCompress.Items = unique(newList);
        end

        % Button pushed function: Button_ChooseDestination
        function Button_ChooseDestinationPushed(app, event)
            %             defaultPath = app.LogSaveDir.Value; % pwd if not valid
            defaultPath = '';
            selpath = uigetdir(defaultPath,'Select destionation folder for all videos');
            if selpath
                app.EditField_Destination.Value  = selpath;
                app.EditField_Destination.Enable = 'on';
            else
                Button_ResetDestinationPushed(app);
            end
            
        end

        % Button pushed function: Button_ResetDestination
        function Button_ResetDestinationPushed(app, event)
            app.EditField_Destination.Value  = 'same as source video';
            app.EditField_Destination.Enable = 'off';
        end

        % Value changed function: EditField_CompressionFactor
        function EditField_CompressionFactorValueChanged(app, event)
            x264Crf = app.EditField_CompressionFactor.Value;
            estimatedCrf = EstimateCompressionFactor(app,x264Crf);
            app.EditField_CompressionFactorEstimate.Value = ['~' num2str(estimatedCrf,'%4.0f') 'x'];
        end

        % Button pushed function: Button_RunFFMpeg
        function Button_RunFFMpegPushed(app, event)
            
            DeleteSource = app.Switch_DeleteSource.Value;
            % Ask confirmation in case Delete Source is ON
            if strcmp(DeleteSource,'On')
                selection = uiconfirm(app.FFMpegCompressVideosUIFigure,sprintf('You will delete the original video files \n(will try to move them to Recycle Bin).\nProceed?'),'Proceed?',...
                    'Options',{'OK','Cancel'},...
                    'DefaultOption','Cancel',...
                    'Icon','warning');
                if ~strcmp(selection,'OK')
                    app.Switch_DeleteSource.Value = 'Off';
                    return
                end
            end
            
            x264Crf          = app.EditField_CompressionFactor.Value;
            estimatedCrf_str = app.EditField_CompressionFactorEstimate.Value;
            
            
            nFiles = length(app.ListBox_videos_toCompress.Items);
            Files = struct;
            cnt = 0;
            filePrefix          = app.EditField_FilePrefix.Value;
            fileSuffix          = app.EditField_FileSuffix.Value;
            if app.EditField_Destination.Enable
                destinationFolder   = app.EditField_Destination.Value;
            else
                destinationFolder   = ''; % use same folder as source file
            end
            problem_files_inds = [];
            
            for i = 1 : nFiles
                cnt = cnt + 1;
                fullpath_input = app.ListBox_videos_toCompress.Items{i};
                [filepath,name,ext] = fileparts(fullpath_input);
                Files(cnt).folder_input         = filepath;
                Files(cnt).name                 = name;
                Files(cnt).ext                  = ext;
                Files(cnt).fullpath_input       = fullfile(Files(cnt).folder_input, [Files(cnt).name, Files(cnt).ext]);
                if isempty(destinationFolder)
                    Files(cnt).folder_output    = Files(cnt).folder_input;
                else
                    Files(cnt).folder_output    = destinationFolder;
                end
                Files(cnt).fullpath_output  = fullfile(Files(cnt).folder_output, [filePrefix, Files(cnt).name, fileSuffix, '.mp4']);
                s = dir(Files(cnt).fullpath_input);
                Files(cnt).size_bytes_input = s.bytes;
            end
            
            fprintf('\n\n')
            fprintf('****************************************\n')
            fprintf('FFMpeg started! %s \n', datestr(now))
            fprintf(' Compression factor x264Crf: %d \n Estimated file size compression: %s \n', x264Crf, estimatedCrf_str)
            fprintf('\n')
            
            for ff = 1 : nFiles
                
                fprintf(' * File %d/%d - "%s%s" \n', ff, nFiles, Files(ff).name, Files(ff).ext)
                
                % ffmpegtranscode wants the frame rate. This info is not included in
                % the file info details read by ffmpeginfo, but it is there with
                % VideoReader:
                % (Estimated frame rate: vidObj.FrameRate)
                
                try
                    try
                        info = ffmpeginfo(Files(ff).fullpath_input);
                        fps = info.streams.codec.fps;
                    catch
                        fps = [];
                    end
                    if isempty(fps)
                        vidObj = VideoReader(Files(ff).fullpath_input); %#ok<TNMLP>
                        fps = vidObj.FrameRate;
                        delete(vidObj); % Necessary if file needs to be deleted!
                    end
                    
                    %% Run ffmpeg
    %                 fprintf(' * File %d/%d - "%s%s" \n', ff, nFiles, Files(ff).name, Files(ff).ext)
                    fprintf('   - Original file size: %3.2f GB\n', Files(ff).size_bytes_input/1024/1024/1024)
                    fprintf('   - Compressing... (it might take a few minutes) \n')
                    
                    tic
                    ffmpegtranscode(Files(ff).fullpath_input, Files(ff).fullpath_output,...
                        'InputFrameRate',fps,...
                        'VideoCodec','x264',...
                        'x264Crf',x264Crf,...
                        'DeleteSource', 'off',...
                        'ProgressFcn','none'... do not show progress bar (it does not work well)
                        );
                    timeElapsed = toc;
                
                catch
                    fprintf('   |!|!|!|\n   |!|!|!| Some error with this file, skipping...\n   |!|!|!| \n')
                    problem_files_inds = [problem_files_inds; ff]; %#ok<AGROW> 
                    continue
                end
                
                ss = dir(Files(ff).fullpath_output);
                Files(ff).size_bytes_output = ss.bytes;
                fprintf('     - ...Done\n')
                fprintf('     - Compressed file size: %3.2f GB (%3.1fX)\n', Files(ff).size_bytes_output/1024/1024/1024, Files(ff).size_bytes_input/Files(ff).size_bytes_output)
                fprintf('     - Time elapsed: %3.1f min\n', timeElapsed/60)

                % Check again here if the switch DeleteSource is still On
                DeleteSource = app.Switch_DeleteSource.Value;
                if strcmpi(DeleteSource,'on')
                    previousState = recycle('on');
                    delete( Files(ff).fullpath_input );
                    if ~isfile(Files(ff).fullpath_input)
                        ls_recycleBin = dir(['C:\$Recycle.Bin\**\' Files(ff).name, Files(ff).ext]);
                        if isempty(ls_recycleBin)
                            fprintf('   - Original file was deleted (maybe permanently)\n')
                        else
                            fprintf('   - Original file was moved to Recycle Bin\n')
                        end
                    end
                    recycle(previousState);
                else
                    fprintf('\n')
                end
                
                if app.CancelNextVideoButton.Value > 0
                    fprintf('\n')
                    fprintf('Cancel button pressed...\n')
                    fprintf('...Aborting \n')
                    break
                end
                
            end
            
            fprintf('\n')
            fprintf('FFMpeg done! %s \n', datestr(now))
            fprintf('****************************************\n\n')
            
            
            fprintf('Nr. problematic files: %d/%d\n', length(problem_files_inds),nFiles)
            if ~isempty(problem_files_inds)
                for pf = 1 : length(problem_files_inds)
                    pff = problem_files_inds(pf);
                    fprintf('  * Problematic file %d/%d - "%s%s" \n', pf, length(problem_files_inds), Files(pff).name, Files(pff).ext)
                end
            end
            fprintf('****************************************\n\n')
            
            fileInds_toClear = find(contains(app.ListBox_videos_all.Items, app.ListBox_videos_toCompress.Items(1:ff)));
            app.ListBox_videos_all.Items(fileInds_toClear) = [];
            app.ListBox_videos_toCompress.Items = {};
            app.VideoFiles = Files;
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            web('https://github.com/lachioma/FFMpeg-compress-videos#how-to-use-the-app','-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FFMpegCompressVideosUIFigure = uifigure('Visible', 'off');
            app.FFMpegCompressVideosUIFigure.Name = 'FFMpegCompressVideos';
            app.FFMpegCompressVideosUIFigure.Icon = 'ffmpeg-logo.png';
            app.ListofallavifilesintheselectedfoldersincludingsubfoldersLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.ListofallavifilesintheselectedfoldersincludingsubfoldersLabel.Text = 'List of all .avi files in the selected folders (including subfolders)';
            app.ListBox_videos_all = uilistbox(app.FFMpegCompressVideosUIFigure);
            app.ListBox_videos_all.Items = {};
            app.ListBox_videos_all.Multiselect = 'on';
            app.ListBox_videos_all.Value = {};
            app.ListofavivideostocompressLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.ListofavivideostocompressLabel.Text = 'List of .avi videos to compress';
            app.ListBox_videos_toCompress = uilistbox(app.FFMpegCompressVideosUIFigure);
            app.ListBox_videos_toCompress.Items = {};
            app.ListBox_videos_toCompress.Multiselect = 'on';
            app.ListBox_videos_toCompress.Value = {};
            app.Button_ChooseFolders = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Button_ChooseFolders.ButtonPushedFcn = createCallbackFcn(app, @Button_ChooseFoldersPushed, true);
            app.Button_ChooseFolders.Text = 'Choose source files or folders';
            app.Button_RemoveVideos = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Button_RemoveVideos.ButtonPushedFcn = createCallbackFcn(app, @Button_RemoveVideosPushed, true);
            app.Button_RemoveVideos.Text = 'remove selected from list';
            app.Button_AddVideos = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Button_AddVideos.ButtonPushedFcn = createCallbackFcn(app, @Button_AddVideosPushed, true);
            app.Button_AddVideos.Text = 'add selected videos --->';
            app.Compressionfactorx264CrfLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.Compressionfactorx264CrfLabel.Tooltip = {'Integer scaler between 1 and 51 {18} x264 video codec constant rate factor. The lower the higher quality, 18 is considered perceptually indistinguishable to lossless. Change by ±6 roughly doubles/halves the file size.'};
            app.Compressionfactorx264CrfLabel.Text = {'Compression factor'; 'x264Crf'};
            app.EditField_CompressionFactor = uieditfield(app.FFMpegCompressVideosUIFigure, 'numeric');
            app.EditField_CompressionFactor.Limits = [1 51];
            app.EditField_CompressionFactor.ValueChangedFcn = createCallbackFcn(app, @EditField_CompressionFactorValueChanged, true);
            app.EditField_CompressionFactor.Tooltip = {'Integer scaler between 1 and 51 {18} x264 video codec constant rate factor. The lower the higher quality, 18 is considered perceptually indistinguishable to lossless. Change by ±6 roughly doubles/halves the file size.'};
            app.EditField_CompressionFactor.Value = 25;
            app.DeletesourcefilesSwitchLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.DeletesourcefilesSwitchLabel.Text = 'Delete source files';
            app.Switch_DeleteSource = uiswitch(app.FFMpegCompressVideosUIFigure, 'slider');
            app.FileprefixEditFieldLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.FileprefixEditFieldLabel.Text = 'File prefix';
            app.EditField_FilePrefix = uieditfield(app.FFMpegCompressVideosUIFigure, 'text');
            app.FilesuffixLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.FilesuffixLabel.Text = 'File suffix';
            app.EditField_FileSuffix = uieditfield(app.FFMpegCompressVideosUIFigure, 'text');
            app.DestinationfolderLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.DestinationfolderLabel.Text = {'Destination'; 'folder'};
            app.EditField_Destination = uieditfield(app.FFMpegCompressVideosUIFigure, 'text');
            app.EditField_Destination.Editable = 'off';
            app.EditField_Destination.Enable = 'off';
            app.EditField_Destination.Value = 'same as source video';
            app.Button_ChooseDestination = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Button_ChooseDestination.ButtonPushedFcn = createCallbackFcn(app, @Button_ChooseDestinationPushed, true);
            app.Button_ChooseDestination.Text = 'choose';
            app.Button_ResetDestination = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Button_ResetDestination.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetDestinationPushed, true);
            app.Button_ResetDestination.Text = 'reset to same as source';
            app.Button_RunFFMpeg = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Button_RunFFMpeg.ButtonPushedFcn = createCallbackFcn(app, @Button_RunFFMpegPushed, true);
            app.Button_RunFFMpeg.Text = 'Run FFMpeg!';
            app.EditField_CompressionFactorEstimate = uieditfield(app.FFMpegCompressVideosUIFigure, 'text');
            app.EditField_CompressionFactorEstimate.Editable = 'off';
            app.EditField_CompressionFactorEstimate.Tooltip = {'Estimated compression factor (not so reliable, depends on file size and compression factor)'};
            app.EditField_CompressionFactorEstimate.Value = 'x';
            app.Image = uiimage(app.FFMpegCompressVideosUIFigure);
            app.Image.ImageSource = 'ffmpeg-logo.png';
            app.FFMpegCompressVideosLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.FFMpegCompressVideosLabel.Text = 'FFMpeg Compress Videos';
            app.AddtooutputfilenameLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.AddtooutputfilenameLabel.Text = {'Add to'; 'output filename'};
            app.mp4Label = uilabel(app.FFMpegCompressVideosUIFigure);
            app.mp4Label.Text = '.mp4';
            app.Help = uibutton(app.FFMpegCompressVideosUIFigure, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.VersionLabel = uilabel(app.FFMpegCompressVideosUIFigure);
            app.VersionLabel.Text = 'lachioma v1.1';
            app.CancelNextVideoButton = uibutton(app.FFMpegCompressVideosUIFigure, 'state');
            app.CancelNextVideoButton.Tooltip = {'Cancel before starting the next video. It does not interrupt current video. Just press Control+C to force cancel.'};
            app.CancelNextVideoButton.Text = 'Cancel next video';
            % Show the figure after all components are created
            app.FFMpegCompressVideosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFMpegVideos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FFMpegCompressVideosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FFMpegCompressVideosUIFigure)
        end
    end
end